classdef EE6618 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EE6618Group2UIFigure         matlab.ui.Figure
        ImageLoadLabel               matlab.ui.control.Label
        UIAxes_Holo_R                matlab.ui.control.UIAxes
        UIAxes_Holo_I                matlab.ui.control.UIAxes
        UIAxes_Recon                 matlab.ui.control.UIAxes
        UIAxes_Orig                  matlab.ui.control.UIAxes
        LoadButton                   matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        ProcessButton                matlab.ui.control.Button
        EnableRNACheckBox            matlab.ui.control.CheckBox
        EnableGridcrossDownsamplingCheckBox  matlab.ui.control.CheckBox
        PSNRLabel                    matlab.ui.control.Label
        Enable2depthreconCheckBox    matlab.ui.control.CheckBox
        EnableOSPRCheckBox           matlab.ui.control.CheckBox
        SubhologramsNumberEditFieldLabel  matlab.ui.control.Label
        SubhologramsNumberEditField  matlab.ui.control.EditField
        DownsamplingparameterEditFieldLabel  matlab.ui.control.Label
        DownSamplingEditField        matlab.ui.control.EditField
        ReconDepthDropDownLabel      matlab.ui.control.Label
        ReconDepthDropDown           matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        orig_path;
        holo_path;
        recon_path;
        orig_dir;
        holo_dir;
        recon_dir;
        orig_img;
        holo_r_img;
        holo_i_img;
        recon_img;
        psnr;
        depth = 1;
        select = 0;
        downsample = 6;
        sub_num = 5;
        use_two_depth = false;
        use_OSPR = false;
        holo_r_name = '\holo_r.jpg';
        holo_i_name = '\holo_i.jpg';
        recon_name = '\recon.jpg';
        save_path;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.jpeg'});
            app.orig_path = fullfile(path, file);
            app.orig_img = imread(fullfile(path,file));
            
            app.ImageLoadLabel.Text = app.orig_path;
            axis(app.UIAxes_Orig, 'tight')
            imshow(app.orig_img,'Parent',app.UIAxes_Orig);
            app.PSNRLabel.Text = 'PSNR: N/A';
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            app.downsample = str2double(app.DownSamplingEditField.Value);
            %app.depth = str2double(app.ReconDepthEditField.Value);
            app.sub_num = str2double(app.SubhologramsNumberEditField.Value);
            
            if app.use_two_depth
                if app.use_OSPR
                    [app.recon_img, app.holo_r_img, app.holo_i_img] = OSPR_RECONS(app.orig_img, app.sub_num, app.depth);
                else
                    [app.recon_img, app.holo_r_img, app.holo_i_img] = TwoDepth_RECONS(app.orig_img, app.select, app.depth, app.downsample);
                end
            else
                [app.recon_img, app.holo_r_img, app.holo_i_img] = Generate_Reconstruct(app.orig_img, app.select, app.downsample);
            end
            
            axis(app.UIAxes_Recon, 'tight')
            axis(app.UIAxes_Holo_R, 'tight')
            axis(app.UIAxes_Holo_I, 'tight')
            imshow(app.recon_img,'Parent',app.UIAxes_Recon);
            imshow(app.holo_r_img,'Parent',app.UIAxes_Holo_R);
            imshow(app.holo_i_img,'Parent',app.UIAxes_Holo_I);
            app.psnr = PSNR(app.orig_img, app.recon_img);
            app.PSNRLabel.Text = ['PSNR: ' num2str(app.psnr)];
        end

        % Value changed function: EnableRNACheckBox
        function EnableRNACheckBoxValueChanged(app, event)
            value = app.EnableRNACheckBox.Value;
            if value == 1
                app.select = 1;
                app.EnableGridcrossDownsamplingCheckBox.Enable = 'off';
            else
                app.select = 0;
                app.EnableGridcrossDownsamplingCheckBox.Enable = 'on';
            end
        end

        % Value changed function: 
        % EnableGridcrossDownsamplingCheckBox
        function EnableGridcrossDownsamplingCheckBoxValueChanged(app, event)
            value = app.EnableGridcrossDownsamplingCheckBox.Value;
            if value == true
                app.select = 2;
                app.DownSamplingEditField.Editable = 'on';
                app.EnableRNACheckBox.Enable = 'off';
            else
                app.select = 0;
                app.DownSamplingEditField.Editable = 'off';
                app.EnableRNACheckBox.Enable = 'on';
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.save_path = uigetdir();
            imwrite(app.recon_img, [app.save_path app.recon_name])
            imwrite(app.holo_i_img, [app.save_path app.holo_i_name])
            imwrite(app.holo_r_img, [app.save_path app.holo_r_name])
            message = sprintf('Successfully saved!');
            uialert(app.EE6618Group2UIFigure,message,'success',...
            'Icon','success');
        end

        % Value changed function: Enable2depthreconCheckBox
        function Enable2depthreconCheckBoxValueChanged(app, event)
            value = app.Enable2depthreconCheckBox.Value;
            if value == true
                app.use_two_depth = true;
                app.ReconDepthDropDown.Enable = 'on';
                app.EnableOSPRCheckBox.Enable = 'on';
            else
                app.use_two_depth = false;
                app.ReconDepthDropDown.Enable = 'off';
                app.EnableOSPRCheckBox.Enable = 'off';
            end
        end

        % Value changed function: EnableOSPRCheckBox
        function EnableOSPRCheckBoxValueChanged(app, event)
            value = app.EnableOSPRCheckBox.Value;
            if value == true
                app.use_OSPR = true;
                app.SubhologramsNumberEditField.Editable = 'on';
            else
                app.use_OSPR = false;
                app.SubhologramsNumberEditField.Editable = 'off';
            end
        end

        % Value changed function: ReconDepthDropDown
        function ReconDepthDropDownValueChanged(app, event)
            value = app.ReconDepthDropDown.Value;
            if value == 'Depth1 0.16'
                app.depth = 1;
            else
                app.depth = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EE6618Group2UIFigure = uifigure('Visible', 'off');
            app.EE6618Group2UIFigure.Name = 'EE6618 Group 2';
            app.ImageLoadLabel = uilabel(app.EE6618Group2UIFigure);
            app.ImageLoadLabel.Text = 'Image Path';
            app.UIAxes_Holo_R = uiaxes(app.EE6618Group2UIFigure);
            title(app.UIAxes_Holo_R, 'Real')
            xlabel(app.UIAxes_Holo_R, '')
            ylabel(app.UIAxes_Holo_R, '')
            app.UIAxes_Holo_R.XTick = [];
            app.UIAxes_Holo_R.YTick = [];
            app.UIAxes_Holo_I = uiaxes(app.EE6618Group2UIFigure);
            title(app.UIAxes_Holo_I, 'Imaginary')
            xlabel(app.UIAxes_Holo_I, '')
            ylabel(app.UIAxes_Holo_I, '')
            app.UIAxes_Holo_I.XTick = [];
            app.UIAxes_Holo_I.YTick = [];
            app.UIAxes_Recon = uiaxes(app.EE6618Group2UIFigure);
            title(app.UIAxes_Recon, 'Reconstruction')
            xlabel(app.UIAxes_Recon, '')
            ylabel(app.UIAxes_Recon, '')
            app.UIAxes_Recon.XTick = [];
            app.UIAxes_Recon.YTick = [];
            app.UIAxes_Orig = uiaxes(app.EE6618Group2UIFigure);
            title(app.UIAxes_Orig, 'Original')
            xlabel(app.UIAxes_Orig, '')
            ylabel(app.UIAxes_Orig, '')
            app.UIAxes_Orig.XTick = [];
            app.UIAxes_Orig.YTick = [];
            app.LoadButton = uibutton(app.EE6618Group2UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.EE6618Group2UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ProcessButton = uibutton(app.EE6618Group2UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.EnableRNACheckBox = uicheckbox(app.EE6618Group2UIFigure);
            app.EnableRNACheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableRNACheckBoxValueChanged, true);
            app.EnableRNACheckBox.Text = 'Enable RNA';
            app.EnableGridcrossDownsamplingCheckBox = uicheckbox(app.EE6618Group2UIFigure);
            app.EnableGridcrossDownsamplingCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableGridcrossDownsamplingCheckBoxValueChanged, true);
            app.EnableGridcrossDownsamplingCheckBox.Text = 'Enable Grid-cross Down-sampling';
            app.PSNRLabel = uilabel(app.EE6618Group2UIFigure);
            app.PSNRLabel.Text = 'PSNR: N/A';
            app.Enable2depthreconCheckBox = uicheckbox(app.EE6618Group2UIFigure);
            app.Enable2depthreconCheckBox.ValueChangedFcn = createCallbackFcn(app, @Enable2depthreconCheckBoxValueChanged, true);
            app.Enable2depthreconCheckBox.Text = 'Enable 2 depth recon ';
            app.EnableOSPRCheckBox = uicheckbox(app.EE6618Group2UIFigure);
            app.EnableOSPRCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableOSPRCheckBoxValueChanged, true);
            app.EnableOSPRCheckBox.Text = 'Enable OSPR';
            app.SubhologramsNumberEditFieldLabel = uilabel(app.EE6618Group2UIFigure);
            app.SubhologramsNumberEditFieldLabel.Text = 'Sub-holograms Number:';
            app.SubhologramsNumberEditField = uieditfield(app.EE6618Group2UIFigure, 'text');
            app.SubhologramsNumberEditField.Editable = 'off';
            app.SubhologramsNumberEditField.Value = '5';
            app.DownsamplingparameterEditFieldLabel = uilabel(app.EE6618Group2UIFigure);
            app.DownsamplingparameterEditFieldLabel.Text = 'Down-sampling parameter:';
            app.DownSamplingEditField = uieditfield(app.EE6618Group2UIFigure, 'text');
            app.DownSamplingEditField.Editable = 'off';
            app.DownSamplingEditField.Value = '6';
            app.ReconDepthDropDownLabel = uilabel(app.EE6618Group2UIFigure);
            app.ReconDepthDropDownLabel.Text = 'Recon Depth:';
            app.ReconDepthDropDown = uidropdown(app.EE6618Group2UIFigure);
            app.ReconDepthDropDown.Items = {'Depth1 0.16', 'Depth2 0.18'};
            app.ReconDepthDropDown.ValueChangedFcn = createCallbackFcn(app, @ReconDepthDropDownValueChanged, true);
            app.ReconDepthDropDown.Enable = 'off';
            app.ReconDepthDropDown.Value = 'Depth1 0.16';
            % Show the figure after all components are created
            app.EE6618Group2UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EE6618

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EE6618Group2UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EE6618Group2UIFigure)
        end
    end
end